classdef hydrostaticdash < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OutputPathEditField             matlab.ui.control.EditField
        OutputPathEditFieldLabel        matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        Gauge                           matlab.ui.control.Gauge
        ComsLamp                        matlab.ui.control.Lamp
        ComsLampLabel                   matlab.ui.control.Label
        AboveSetpointLamp               matlab.ui.control.Lamp
        AboveSetpointLampLabel          matlab.ui.control.Label
        AtSetpointLamp                  matlab.ui.control.Lamp
        AtSetpointLampLabel             matlab.ui.control.Label
        SetpointRangePSIEditField       matlab.ui.control.NumericEditField
        SetpointRangePSIEditFieldLabel  matlab.ui.control.Label
        TestSetpointPSIEditField        matlab.ui.control.NumericEditField
        TestSetpointPSIEditFieldLabel   matlab.ui.control.Label
        ESTOPButton                     matlab.ui.control.Button
        UnsafeLamp                      matlab.ui.control.Lamp
        UnsafeLampLabel                 matlab.ui.control.Label
        SafeLamp                        matlab.ui.control.Lamp
        SafeLampLabel                   matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        AutoAbortLamp                   matlab.ui.control.Lamp
        AutoAbortLampLabel              matlab.ui.control.Label
        AbortSetpointPSIEditField       matlab.ui.control.NumericEditField
        AbortSetpointPSIEditFieldLabel  matlab.ui.control.Label
        ValveControlPanel               matlab.ui.container.Panel
        OPENEditField                   matlab.ui.control.NumericEditField
        OPENEditFieldLabel              matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        OpenButton                      matlab.ui.control.Button
        CLOSEDLamp                      matlab.ui.control.Lamp
        CLOSEDLampLabel                 matlab.ui.control.Label
        SafetyPanel                     matlab.ui.container.Panel
        StoppedLamp                     matlab.ui.control.Lamp
        StoppedLampLabel                matlab.ui.control.Label
        SetpointPanel                   matlab.ui.container.Panel
        PSIPanel                        matlab.ui.container.Panel
        PSIEditField                    matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Public properties
    properties (Access = private)
        updateTimer % Timer for update function
        vstate string = "OPEN";  % valve state
        state string = "RESET";
        vopensetpoint double = 0; % %
        PRESSURE double = [0 0 0 0 0 0 0 0 0 0];      % scalar or growing vector
        TIME = [0 0 0 0 0 0 0 0 0 0];
        maxPressure = 10; % Max safe pressure
        abortSetpoint = 50;
        commState string = "OFF";
        testSetpoint = 0;
        testSetpointTol = 0;
        port = 'COM4';
        baud = 9600;
        arduino;
        writePath string;
    end

    methods (Access = private)
        %updates gui elements based on app.PRESSURE state
        
        function updateGUI(app)
            try
                %% Arduino Comm Check
                try
                    writeline(app.arduino, "COMCHECK");
                    pause(0.03);
                    message = readline(app.arduino);
                    if(message == "HERE")
                        app.commState = "ON";
                    else
                        app.commState = "OFF";
                    end
                catch
                    app.commState = "OFF";
                end

                %% Respond to com state
                if(app.commState == "OFF")
                    app.ComsLamp.Color = 'w';
                else
                    app.ComsLamp.Color = 'g';
                    % Get pressure, timestamp from arduino 
                    %app.PRESSURE = [app.PRESSURE, rand*6];
                    %app.time = [app.time, app.time(end)+1];
                end
                
                %% Check Auto Abort
                if(app.PRESSURE(end) > app.abortSetpoint)
                    app.state = "AUTOABORT";
                end
                %% Perform State Actions
                if(app.state == "ESTOP")
                    app.vopensetpoint = 100;
                    app.OPENEditField.Value = app.vopensetpoint;
                    app.vstate = "OPEN";
                    app.StoppedLamp.Color = 'r';
                    % estop stuff
                elseif (app.state == "AUTOABORT")
                    app.vopensetpoint = 100;
                    app.OPENEditField.Value = app.vopensetpoint;
                    app.StoppedLamp.Color = 'r';
                    app.AutoAbortLamp.Color = 'r';
                    app.vstate = "OPEN";
                    % pressure triggered estop 
                else
                    app.StoppedLamp.Color = 'w';
                    app.AutoAbortLamp.Color = 'w';
                    % reset/nominal state
                end
                %% Update Indicators after performing state actions
                if(app.vstate == "OPEN")
                    app.CLOSEDLamp.Color = 'w';
                else
                    app.CLOSEDLamp.Color = 'r';
                end
                if(mean(app.PRESSURE(end-5:end)) > app.maxPressure || app.vstate == "CLOSE")
                    app.UnsafeLamp.Color = 'r';
                    app.SafeLamp.Color = "w";
                else
                    app.UnsafeLamp.Color = 'w';
                    app.SafeLamp.Color = "g";
                end
                disp("UPDATING GAUGES")
                %% Update pressure plots/gauges
                app.Gauge.Value = app.PRESSURE(end);
                app.PSIEditField.Value = app.PRESSURE(end);
                N = 100;
                n = numel(app.PRESSURE);
                idx = max(1, n - N + 1):n;
                plot(app.UIAxes, app.TIME(idx)/1000, app.PRESSURE(idx));
                %% Update Setpoint Inidcators
                if(abs(app.PRESSURE(end)-app.testSetpoint)< app.testSetpointTol)
                    app.AtSetpointLamp.Color = 'g';
                    app.AboveSetpointLamp.Color = 'w';
                elseif((app.PRESSURE(end)-app.testSetpoint) > (app.testSetpointTol))
                    app.AtSetpointLamp.Color = 'w';
                    app.AboveSetpointLamp.Color = 'r';
                else
                    app.AtSetpointLamp.Color = 'w';
                    app.AboveSetpointLamp.Color = 'w';
                end


                %% Command arduino to match valve state and send new data
                if(app.commState == "ON")
                    if(app.vstate == "OPEN")
                        message = int2str(round(app.vopensetpoint));
                        writeline(app.arduino, "OPEN");
                        writeline(app.arduino, message);
                    else
                        writeline(app.arduino, "CLOSE");
                    end
                    writeline(app.arduino, "PRESSURE");
                    pstr = strtrim(readline(app.arduino));
                    pval = str2double(pstr);
                    app.PRESSURE = [app.PRESSURE, pval];
                    writeline(app.arduino, "TIME");
                    tstr = strtrim(readline(app.arduino));
                    tval = str2double(tstr);
                    app.TIME = [app.TIME, tval];
                    disp(app.TIME(end));
                end
                catch ME
            % temporary: show where the crash came from
            s = ME.stack; 
            if ~isempty(s)
                fprintf(2,'updateGUI crashed in %s (line %d): %s\n', s(1).name, s(1).line, ME.message);
            else
                fprintf(2,'updateGUI crashed: %s\n', ME.message);
            end
            end
            
                
            end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.updateTimer = timer("ExecutionMode","fixedRate","Period",0.15, "BusyMode","drop","TimerFcn",@(~,~) app.updateGUI());
        end

        % Button pushed function: ESTOPButton
        function ESTOPButtonPushed(app, event)
            app.state = "ESTOP";
        
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.state = "RESET";
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.vstate = "OPEN";
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.vstate = "CLOSE";
        end

        % Value changed function: OPENEditField
        function OPENEditFieldValueChanged(app, event)
            app.vopensetpoint = app.OPENEditField.Value;
            
        end

        % Value changed function: PSIEditField
        function PSIEditFieldValueChanged(app, event)


        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if(strcmp(app.updateTimer.Running,"off"))
                app.writePath = app.OutputPathEditField.Value;
                app.arduino = serialport("COM4", 9600);
                configureCallback(app.arduino, "off");          % we'll do polling, not callbacks
                configureTerminator(app.arduino,"CR/LF");
                app.arduino.Timeout = 0.5;                     % short timeout
                flush(app.arduino);

                
                app.StartButton.Text = "Running";
                start(app.updateTimer)
                

            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StartButton.Text = "Start";
    
            % Stop the timer
            if isvalid(app.updateTimer)
                stop(app.updateTimer);
            end
            fname = app.writePath + "/Pressure Recording " + datestr(now,'yyyy-mm-dd_HHMMSS') + ".csv";
            disp(fname);
            writematrix([app.TIME' app.PRESSURE'], fname);
            app.TIME = [0 0 0 0 0 0];
            app.PRESSURE = [0 0 0 0 0 0];
            try
                if ~isempty(app.arduino) && isvalid(app.arduino)
                    clear app.arduino;              % remove property reference
                end
            catch
                % ignore if already cleared
            end
            
            % Extra safety: close all lingering serialports in MATLAB
            oldports = instrfindall;   % legacy objects
            if ~isempty(oldports)
                fclose(oldports);
                delete(oldports);
            end
            if ~isempty(serialportfind("all"))     % for newer serialport interface
                ports = serialportfind("all");
                for k = 1:numel(ports)
                    delete(ports{k});
                end
            end
            
        end

        % Value changed function: AbortSetpointPSIEditField
        function AbortSetpointPSIEditFieldValueChanged(app, event)
            app.abortSetpoint = app.AbortSetpointPSIEditField.Value;
            
        end

        % Value changed function: TestSetpointPSIEditField
        function TestSetpointPSIEditFieldValueChanged(app, event)
            app.testSetpoint = app.TestSetpointPSIEditField.Value;
            
        end

        % Value changed function: SetpointRangePSIEditField
        function SetpointRangePSIEditFieldValueChanged(app, event)
            app.testSetpointTol = app.SetpointRangePSIEditField.Value;
            
        end

        % Value changed function: OutputPathEditField
        function OutputPathEditFieldValueChanged(app, event)
            app.writePath = app.OutputPathEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PSIPanel = uipanel(app.UIFigure);
            app.PSIPanel.Title = 'PSI';
            app.PSIEditField = uieditfield(app.PSIPanel, 'numeric');
            app.PSIEditField.ValueChangedFcn = createCallbackFcn(app, @PSIEditFieldValueChanged, true);
            app.SetpointPanel = uipanel(app.UIFigure);
            app.SetpointPanel.Title = 'Setpoint';
            app.SafetyPanel = uipanel(app.UIFigure);
            app.SafetyPanel.Title = 'Safety';
            app.StoppedLampLabel = uilabel(app.SafetyPanel);
            app.StoppedLampLabel.Text = 'Stopped';
            app.StoppedLamp = uilamp(app.SafetyPanel);
            app.ValveControlPanel = uipanel(app.UIFigure);
            app.ValveControlPanel.Title = 'Valve Control';
            app.CLOSEDLampLabel = uilabel(app.ValveControlPanel);
            app.CLOSEDLampLabel.Text = 'CLOSED';
            app.CLOSEDLamp = uilamp(app.ValveControlPanel);
            app.OpenButton = uibutton(app.ValveControlPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.CloseButton = uibutton(app.ValveControlPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OPENEditFieldLabel = uilabel(app.ValveControlPanel);
            app.OPENEditFieldLabel.Text = '% OPEN';
            app.OPENEditField = uieditfield(app.ValveControlPanel, 'numeric');
            app.OPENEditField.Limits = [1 100];
            app.OPENEditField.ValueChangedFcn = createCallbackFcn(app, @OPENEditFieldValueChanged, true);
            app.OPENEditField.Value = 100;
            app.AbortSetpointPSIEditFieldLabel = uilabel(app.UIFigure);
            app.AbortSetpointPSIEditFieldLabel.Text = 'Abort Setpoint  (PSI)';
            app.AbortSetpointPSIEditField = uieditfield(app.UIFigure, 'numeric');
            app.AbortSetpointPSIEditField.ValueChangedFcn = createCallbackFcn(app, @AbortSetpointPSIEditFieldValueChanged, true);
            app.AbortSetpointPSIEditField.Value = 50;
            app.AutoAbortLampLabel = uilabel(app.UIFigure);
            app.AutoAbortLampLabel.Text = 'Auto Abort';
            app.AutoAbortLamp = uilamp(app.UIFigure);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SafeLampLabel = uilabel(app.UIFigure);
            app.SafeLampLabel.Text = 'Safe';
            app.SafeLamp = uilamp(app.UIFigure);
            app.UnsafeLampLabel = uilabel(app.UIFigure);
            app.UnsafeLampLabel.Text = 'Unsafe';
            app.UnsafeLamp = uilamp(app.UIFigure);
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @ESTOPButtonPushed, true);
            app.ESTOPButton.Icon = fullfile(pathToMLAPP, 'e stop.png');
            app.ESTOPButton.Text = 'E-STOP';
            app.TestSetpointPSIEditFieldLabel = uilabel(app.UIFigure);
            app.TestSetpointPSIEditFieldLabel.Text = 'Test Setpoint  (PSI)';
            app.TestSetpointPSIEditField = uieditfield(app.UIFigure, 'numeric');
            app.TestSetpointPSIEditField.ValueChangedFcn = createCallbackFcn(app, @TestSetpointPSIEditFieldValueChanged, true);
            app.SetpointRangePSIEditFieldLabel = uilabel(app.UIFigure);
            app.SetpointRangePSIEditFieldLabel.Text = 'Setpoint Range (PSI)';
            app.SetpointRangePSIEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetpointRangePSIEditField.ValueChangedFcn = createCallbackFcn(app, @SetpointRangePSIEditFieldValueChanged, true);
            app.AtSetpointLampLabel = uilabel(app.UIFigure);
            app.AtSetpointLampLabel.Text = 'At Setpoint';
            app.AtSetpointLamp = uilamp(app.UIFigure);
            app.AboveSetpointLampLabel = uilabel(app.UIFigure);
            app.AboveSetpointLampLabel.Text = 'Above Setpoint';
            app.AboveSetpointLamp = uilamp(app.UIFigure);
            app.ComsLampLabel = uilabel(app.UIFigure);
            app.ComsLampLabel.Text = 'Coms';
            app.ComsLamp = uilamp(app.UIFigure);
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.Gauge.Limits = [0 1000];
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.OutputPathEditFieldLabel = uilabel(app.UIFigure);
            app.OutputPathEditFieldLabel.Text = 'Output Path';
            app.OutputPathEditField = uieditfield(app.UIFigure, 'text');
            app.OutputPathEditField.ValueChangedFcn = createCallbackFcn(app, @OutputPathEditFieldValueChanged, true);
            app.OutputPathEditField.Value = 'C:\Users\isaac\Documents\LERT\DASH';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hydrostaticdash

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end